classdef Ecualizador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EcualizadorPanel          matlab.ui.container.Panel
        PredefinidoDropDownLabel  matlab.ui.control.Label
        PredefinidoDropDown       matlab.ui.control.DropDown
        AplicarButton             matlab.ui.control.Button
        RestablecerButton         matlab.ui.control.Button
        ActivoCheckBox            matlab.ui.control.CheckBox
        sld_31hz                  matlab.ui.control.Slider
        sld_64hz                  matlab.ui.control.Slider
        sld_125hz                 matlab.ui.control.Slider
        sld_250hz                 matlab.ui.control.Slider
        sld_500hz                 matlab.ui.control.Slider
        sld_2khz                  matlab.ui.control.Slider
        sld_1khz                  matlab.ui.control.Slider
        sld_4khz                  matlab.ui.control.Slider
        sld_8khz                  matlab.ui.control.Slider
        sld_16khz                 matlab.ui.control.Slider
        HzLabel                   matlab.ui.control.Label
        HzLabel_2                 matlab.ui.control.Label
        HzLabel_3                 matlab.ui.control.Label
        HzLabel_4                 matlab.ui.control.Label
        KHzLabel                  matlab.ui.control.Label
        HzLabel_5                 matlab.ui.control.Label
        KHzLabel_2                matlab.ui.control.Label
        KHzLabel_3                matlab.ui.control.Label
        KHzLabel_4                matlab.ui.control.Label
        KHzLabel_5                matlab.ui.control.Label
        SealoriginalPanel         matlab.ui.container.Panel
        axes_original             matlab.ui.control.UIAxes
        axes_original_f           matlab.ui.control.UIAxes
        btn_play                  matlab.ui.control.Button
        btn_play_3                matlab.ui.control.Button
        UrlEditFieldLabel         matlab.ui.control.Label
        txt_url                   matlab.ui.control.EditField
        btn_seleccionar           matlab.ui.control.Button
        SealfiltradaPanel         matlab.ui.container.Panel
        axes_filtrada             matlab.ui.control.UIAxes
        axes_filtrada_f           matlab.ui.control.UIAxes
        FreqxMaxSliderLabel       matlab.ui.control.Label
        FreqxMaxSlider            matlab.ui.control.Slider
        SenxButton                matlab.ui.control.Button
        CosxButton                matlab.ui.control.Button
    end


    properties (Access = public)
        %% Parametros del archivo de sonido
        FS; % Frecuencia de muestreo del audio
        X; % Señal de audio
        t_audio; %Eje horizontal para graficar el audio en funcion del tiempo y no de las muestras que se leen
                
        %% Frecuencia
        f_audio; %Eje horizontal para graficar el espectro del audio
        Y; % Señal resultante del filtro
        
        audio; % Audio player        
        SLD = zeros(10,1);
        rock = [4.5, 3.5, 2.5, 1.5, 0, -1, 0, 1, 2.5, 3.5];
        soft = [1.5, 2.1, 1.5, 0.6, -0.4, -2, -3, -1.5, 0, 2.5];
        
    end

    methods (Access = public)
    
        function Y = recalcular_filtros(app)
            
            disp('Recalculando filtros...');
            PBR = ones(1, 10) * 0.25; %[0.5, 0.5, 0.5, 0.5, 0.5, 0.5, 0.5, 0.5, 0.5, 0.5];
            SBR = ones(1, 10) * 4; %[6, 6, 6, 6, 6, 6, 6, 6, 6, 6];
            
            %% Primera banda 31Hz
            passBand = [16 48]; %% passband
            stopBand = [1 80]; %% stopBand
            PBRipple = PBR(1); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(1); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2); %% Frecuencia passband / la mitad de la frecuencia de mostreo
            Wstopb = stopBand/(app.FS/2); %% Frecuecia stopband / la mitad de la frecuencia de muestreo
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple); %% Orden minimo y frecuencia de corte  
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass'); %% Coeficientes de transferencia para el filtro        
            Y1 = 10^(app.SLD(1)) * filter(b, a, app.X);
                        
            %% Segunda banda 64Hz
            passBand = [48 95];
            stopBand = [31 125];
            PBRipple = PBR(2); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(2); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y2 = 10^(app.SLD(2)) * filter(b, a, app.X);
            
            %% Tercera banda 125Hz
            passBand = [95 188];
            stopBand = [64 250];
            PBRipple = PBR(3); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(3); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y3= 10^(app.SLD(3)) * filter(b, a, app.X);
            
            %% Cuarta banda 250Hz
            passBand = [188 375];
            stopBand = [125 500];
            PBRipple = PBR(4); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(4); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y4 = 10^(app.SLD(4)) * filter(b, a, app.X);
            
            %% Quinta banda 500Hz
            passBand = [375 750];
            stopBand = [250 1000];
            PBRipple = PBR(5); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(5); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y5 = 10^(app.SLD(5)) * filter(b, a, app.X);
            
            %% Sexta banda 1KHz
            passBand = [750 1500];
            stopBand = [500 2000];
            PBRipple = PBR(6); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(6); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y6 = 10^(app.SLD(6)) * filter(b, a, app.X);
            
            %% Septima banda 2KHz
            passBand = [1500 3000];
            stopBand = [1000 4000];
            PBRipple = PBR(7); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(7); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y7 = 10^(app.SLD(7)) * filter(b, a, app.X);
            
            %% Octava banda 4KHz
            passBand = [3000 6000];
            stopBand = [2000 8000];
            PBRipple = PBR(8); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(8); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y8 = 10^(app.SLD(8)) * filter(b, a, app.X);
            
            %% Novena banda 8KHz
            passBand = [6000 12000];
            stopBand = [4000 16000];
            PBRipple = PBR(9); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(9); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');            
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y9 = 10^(app.SLD(9)) * filter(b, a, app.X);
            
            %% Ultima banda 16KHz
            passBand = [12000 20000];
            stopBand = [6000 21000];
            PBRipple = PBR(10); %% maximum permissible passband loss in decibels.
            SBRipple = SBR(10); %% This value is the number of decibels the stopband is down from the passband.
            Wpassb = passBand/(app.FS/2);
            Wstopb = stopBand/(app.FS/2);            
            [Nf, Wfpassb] = cheb1ord(Wpassb, Wstopb, PBRipple, SBRipple,'z');  
            [b, a] = cheby1(Nf, PBRipple, Wstopb, 'bandpass', 'z');            
            Y10 = 10^(app.SLD(10)) * filter(b, a, app.X);
            
            
            % Salida
            Y = Y1 + Y2 + Y3 + Y4 + Y5 + Y6 + Y7 + Y8 + Y9 + Y10;
            Y = Y/max(Y);
            app.Y = Y;
            
            %Graficar filtrada en frecuencias
               specY = abs(fft(app.Y, 20000));
               specY = specY/max(specY);
               specY = specY(1:min(length(specY), app.FreqxMaxSlider.Value));
            
               L = length(specY);
               P2 = abs(specY/L);
               P1 = P2(1:L/2+1);
               P1(2:end-1) = 2*P1(2:end-1);
               f = app.FS*(0:(L/2))/L;
               
               
               subplot(2, 1, 2);
               P1 = semilogx(f, P1);
            % Graficar
            app.Y = app.Y/max(abs(min(app.Y)), abs(max(app.Y)));
            plot(app.axes_filtrada, app.t_audio, app.Y);
            plot(app.axes_filtrada_f, specY);
            
            
            %reproduccion de audio
           %% pause(app.audio);
           %% sample = get(app.audio, 'CurrentSample');
           %% app.audio = audioplayer(app.Y, app.FS);
           %% if(sample > 1)
           %%     play(app.audio, sample);  
        %%else
          %%      play(app.audio);
            %%end


        end
        
    end


    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
          
        end

        % Button pushed function: btn_seleccionar
        function btn_seleccionarPushed(app, event)
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
               disp('No se selecciona nada');
            else
               app.txt_url.Value = fullfile(path,file);
               disp(['Se ha seleccionado ', fullfile(path,file)]);
               
               %Graficar original en el tiempo
               [x,fs] = audioread(fullfile(path,file));
               app.FS = fs;
               app.X = x;
               app.t_audio = linspace(0, length(x)/fs, length(x));
               plot(app.axes_original, app.t_audio, x);
               
               
               %Graficar original en frecuencia
               specx = abs(fft(x));
               %%specx = specx(1:app.FreqxMaxSlider.Value); %% Tomar el valor del slider para el maximo de la frecuencia que se grafica
               app.f_audio = linspace(1, fs/2, length(x)/2);
               
               subplot(2, 2, 1);
               specx = semilogx(specx);
               
               plot(app.axes_original_f, specx);
               
               recalcular_filtros(app);             
            end
        end

        % Button pushed function: btn_play
        function btn_playButtonPushed(app, event)
            if(strcmp(get(app.audio, 'Running'), 'off'))
                if(get(app.audio, 'CurrentSample') > 1 )
                    resume(app.audio);
                else
                    play(app.audio);
                end
            else
                pause(app.audio);
            end
        end

        % Button pushed function: btn_play_3
        function btn_play_3ButtonPushed(app, event)
            stop(app.audio);
        end

        % Value changed function: sld_31hz
        function sld_31hzValueChanged(app, event)
            value = app.sld_31hz.Value;
            app.SLD(1) = value;
            recalcular_filtros(app);      
            
        end

        % Value changed function: sld_16khz
        function sld_16khzValueChanged(app, event)
            value = app.sld_16khz.Value;
            app.SLD(10) = value;
            recalcular_filtros(app);   
        end

        % Value changed function: FreqxMaxSlider
        function FreqxMaxSliderValueChanged(app, event)
            value = app.FreqxMaxSlider.Value;
             %Graficar original en frecuencia
               specx = abs(fft(app.X));
               specx = specx/max(specx);
               specx = specx(1:min(length(specx), value));
               plot(app.axes_original_f, specx);
             %Graficar filtrada en frecuencia
               specy = abs(fft(app.Y));
               specy = specy/max(specy);
               specy = specy(1:min(length(specy), value));
               plot(app.axes_filtrada_f, specy);
        end

        % Value changed function: sld_64hz
        function sld_64hzValueChanged(app, event)
            value = app.sld_64hz.Value;
            app.SLD(2) = value;
            recalcular_filtros(app); 
            
        end

        % Value changed function: sld_125hz
        function sld_125hzValueChanged(app, event)
            value = app.sld_125hz.Value;
            app.SLD(3) = value;
            recalcular_filtros(app);      
            
        end

        % Value changed function: sld_250hz
        function sld_250hzValueChanged(app, event)
            value = app.sld_250hz.Value;
            app.SLD(4) = value;
            recalcular_filtros(app);      
        end

        % Value changed function: sld_500hz
        function sld_500hzValueChanged(app, event)
            value = app.sld_500hz.Value;
            app.SLD(5) = value;
            recalcular_filtros(app);      
        end

        % Value changed function: sld_1khz
        function sld_1khzValueChanged(app, event)
            value = app.sld_1khz.Value;
            app.SLD(6) = value;
            recalcular_filtros(app);      
        end

        % Value changed function: sld_2khz
        function sld_2khzValueChanged(app, event)
            value = app.sld_2khz.Value;
            app.SLD(7) = value;
            recalcular_filtros(app);      
        end

        % Value changed function: sld_4khz
        function sld_4khzValueChanged(app, event)
            value = app.sld_4khz.Value;
            app.SLD(8) = value;
            recalcular_filtros(app);      
        end

        % Value changed function: sld_8khz
        function sld_8khzValueChanged(app, event)
            value = app.sld_8khz.Value;
            app.SLD(9) = value;
            recalcular_filtros(app);      
        end

        % Button pushed function: RestablecerButton
        function RestablecerButtonPushed(app, event)
            app.sld_31hz.Value = 0;
            app.sld_64hz.Value = 0;
            app.sld_125hz.Value = 0;
            app.sld_250hz.Value = 0;
            app.sld_500hz.Value = 0;
            app.sld_1khz.Value = 0;
            app.sld_2khz.Value = 0;
            app.sld_4khz.Value = 0;
            app.sld_8khz.Value = 0;
            app.sld_16khz.Value = 0;
            app.SLD = zeros(1, 10);
            recalcular_filtros(app);
            
        end

        % Button pushed function: SenxButton
        function SenxButtonPushed(app, event)
             %Graficar original en el tiempo
             
                freq=44100;
                Tm=1/freq;
                app.FS=freq;
                
                t=[0:Tm:1/6];
                y=sin((2*pi*60)*t) + sin((2*pi*500)*t) +sin((2*pi*4000)*t) + sin((2*pi*16000)*t);
                app.t_audio = t;
               app.X = y;
               app.t_audio = t;
               plot(app.axes_original, app.t_audio, app.X);
               
               
               %Graficar original en frecuencia
               specx = abs(fft(app.X, 20000));
               specx = specx/max(specx);
               L = length(specx);
               P2 = abs(specx/L);
               P1 = P2(1:L/2+1);
               P1(2:end-1) = 2*P1(2:end-1);
               f = freq*(0:(L/2))/L;
               
               
               subplot(2, 1, 1);
               P1 = semilogx(f, P1);
               
               
               %specx = specx(1:app.FreqxMaxSlider.Value); %% Tomar el valor del slider para el maximo de la frecuencia que se grafica
               plot(app.axes_original_f, specx);
               %ylim([0 20000]);
               
               recalcular_filtros(app);   
        end

        % Button pushed function: CosxButton
        function CosxButtonPushed(app, event)
             %Graficar original en el tiempo
             
                t=[0:0.001:10];
                freq=43E3;
                d=[0:1/freq:10];
                h=pulstran(t,d,'rectpuls',0.01);
                h = h / max(h);
                y=cos(2*pi*t);
                samp=h.*y;
                YY=samp;
                app.FS=freq;
                app.t_audio = t;
                
               app.X = YY;
               app.t_audio = t;
               plot(app.axes_original, app.t_audio, app.X);
               
               
               %Graficar original en frecuencia
               specx = (fft(app.X));
               specx = specx/max(specx);
               %specx = specx(1:app.FreqxMaxSlider.Value); %% Tomar el valor del slider para el maximo de la frecuencia que se grafica
               app.f_audio = linspace(1, app.FS/2, length(app.X)/2);
               plot(app.axes_original_f, specx);
               
               recalcular_filtros(app);   
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            
            aux = app.rock;
            
            app.sld_31hz.Value = aux(1);
            app.sld_64hz.Value = aux(2);
            app.sld_125hz.Value = aux(3);
            app.sld_250hz.Value = aux(4);
            app.sld_500hz.Value = aux(5);
            app.sld_1khz.Value = aux(6);
            app.sld_2khz.Value = aux(7);
            app.sld_4khz.Value = aux(8);
            app.sld_8khz.Value = aux(9);
            app.sld_16khz.Value = aux(10);
            recalcular_filtros(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EcualizadorPanel = uipanel(app.UIFigure);
            app.EcualizadorPanel.Title = 'Ecualizador';
            app.PredefinidoDropDownLabel = uilabel(app.EcualizadorPanel);
            app.PredefinidoDropDownLabel.Text = 'Predefinido';
            app.PredefinidoDropDown = uidropdown(app.EcualizadorPanel);
            app.PredefinidoDropDown.Items = {'Rock', 'Soft'};
            app.PredefinidoDropDown.Value = 'Rock';
            app.AplicarButton = uibutton(app.EcualizadorPanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.RestablecerButton = uibutton(app.EcualizadorPanel, 'push');
            app.RestablecerButton.ButtonPushedFcn = createCallbackFcn(app, @RestablecerButtonPushed, true);
            app.RestablecerButton.Text = 'Restablecer';
            app.ActivoCheckBox = uicheckbox(app.EcualizadorPanel);
            app.ActivoCheckBox.Text = 'Activo';
            app.ActivoCheckBox.Value = true;
            app.sld_31hz = uislider(app.EcualizadorPanel);
            app.sld_31hz.Limits = [-12 12];
            app.sld_31hz.ValueChangedFcn = createCallbackFcn(app, @sld_31hzValueChanged, true);
            app.sld_64hz = uislider(app.EcualizadorPanel);
            app.sld_64hz.Limits = [-12 12];
            app.sld_64hz.ValueChangedFcn = createCallbackFcn(app, @sld_64hzValueChanged, true);
            app.sld_125hz = uislider(app.EcualizadorPanel);
            app.sld_125hz.Limits = [-12 12];
            app.sld_125hz.ValueChangedFcn = createCallbackFcn(app, @sld_125hzValueChanged, true);
            app.sld_250hz = uislider(app.EcualizadorPanel);
            app.sld_250hz.Limits = [-12 12];
            app.sld_250hz.ValueChangedFcn = createCallbackFcn(app, @sld_250hzValueChanged, true);
            app.sld_500hz = uislider(app.EcualizadorPanel);
            app.sld_500hz.Limits = [-12 12];
            app.sld_500hz.ValueChangedFcn = createCallbackFcn(app, @sld_500hzValueChanged, true);
            app.sld_2khz = uislider(app.EcualizadorPanel);
            app.sld_2khz.Limits = [-12 12];
            app.sld_2khz.ValueChangedFcn = createCallbackFcn(app, @sld_2khzValueChanged, true);
            app.sld_1khz = uislider(app.EcualizadorPanel);
            app.sld_1khz.Limits = [-12 12];
            app.sld_1khz.ValueChangedFcn = createCallbackFcn(app, @sld_1khzValueChanged, true);
            app.sld_4khz = uislider(app.EcualizadorPanel);
            app.sld_4khz.Limits = [-12 12];
            app.sld_4khz.ValueChangedFcn = createCallbackFcn(app, @sld_4khzValueChanged, true);
            app.sld_8khz = uislider(app.EcualizadorPanel);
            app.sld_8khz.Limits = [-12 12];
            app.sld_8khz.ValueChangedFcn = createCallbackFcn(app, @sld_8khzValueChanged, true);
            app.sld_16khz = uislider(app.EcualizadorPanel);
            app.sld_16khz.Limits = [-12 12];
            app.sld_16khz.ValueChangedFcn = createCallbackFcn(app, @sld_16khzValueChanged, true);
            app.HzLabel = uilabel(app.EcualizadorPanel);
            app.HzLabel.Text = '64Hz';
            app.HzLabel_2 = uilabel(app.EcualizadorPanel);
            app.HzLabel_2.Text = '32Hz';
            app.HzLabel_3 = uilabel(app.EcualizadorPanel);
            app.HzLabel_3.Text = '250Hz';
            app.HzLabel_4 = uilabel(app.EcualizadorPanel);
            app.HzLabel_4.Text = '125Hz';
            app.KHzLabel = uilabel(app.EcualizadorPanel);
            app.KHzLabel.Text = '1KHz';
            app.HzLabel_5 = uilabel(app.EcualizadorPanel);
            app.HzLabel_5.Text = '500Hz';
            app.KHzLabel_2 = uilabel(app.EcualizadorPanel);
            app.KHzLabel_2.Text = '4KHz';
            app.KHzLabel_3 = uilabel(app.EcualizadorPanel);
            app.KHzLabel_3.Text = '2KHz';
            app.KHzLabel_4 = uilabel(app.EcualizadorPanel);
            app.KHzLabel_4.Text = '16KHz';
            app.KHzLabel_5 = uilabel(app.EcualizadorPanel);
            app.KHzLabel_5.Text = '8KHz';
            app.SealoriginalPanel = uipanel(app.UIFigure);
            app.SealoriginalPanel.Title = 'Señal original';
            app.axes_original = uiaxes(app.SealoriginalPanel);
            title(app.axes_original, 'Tiempo')
            xlabel(app.axes_original, 'X')
            ylabel(app.axes_original, 'Y')
            app.axes_original_f = uiaxes(app.SealoriginalPanel);
            title(app.axes_original_f, 'frecuencia')
            xlabel(app.axes_original_f, 'X')
            ylabel(app.axes_original_f, 'Y')
            app.btn_play = uibutton(app.UIFigure, 'push');
            app.btn_play.ButtonPushedFcn = createCallbackFcn(app, @btn_playButtonPushed, true);
            app.btn_play.Text = 'Play/Pause';
            app.btn_play_3 = uibutton(app.UIFigure, 'push');
            app.btn_play_3.ButtonPushedFcn = createCallbackFcn(app, @btn_play_3ButtonPushed, true);
            app.btn_play_3.Text = 'stop';
            app.UrlEditFieldLabel = uilabel(app.UIFigure);
            app.UrlEditFieldLabel.Text = 'Url';
            app.txt_url = uieditfield(app.UIFigure, 'text');
            app.btn_seleccionar = uibutton(app.UIFigure, 'push');
            app.btn_seleccionar.ButtonPushedFcn = createCallbackFcn(app, @btn_seleccionarPushed, true);
            app.btn_seleccionar.Text = 'Seleccionar..';
            app.SealfiltradaPanel = uipanel(app.UIFigure);
            app.SealfiltradaPanel.Title = 'Señal filtrada';
            app.axes_filtrada = uiaxes(app.SealfiltradaPanel);
            title(app.axes_filtrada, 'Tiempo')
            xlabel(app.axes_filtrada, 'X')
            ylabel(app.axes_filtrada, 'Y')
            app.axes_filtrada_f = uiaxes(app.SealfiltradaPanel);
            title(app.axes_filtrada_f, 'frecuencia')
            xlabel(app.axes_filtrada_f, 'X')
            ylabel(app.axes_filtrada_f, 'Y')
            app.FreqxMaxSliderLabel = uilabel(app.UIFigure);
            app.FreqxMaxSliderLabel.Text = 'Freq. xMax';
            app.FreqxMaxSlider = uislider(app.UIFigure);
            app.FreqxMaxSlider.Limits = [500 21000];
            app.FreqxMaxSlider.ValueChangedFcn = createCallbackFcn(app, @FreqxMaxSliderValueChanged, true);
            app.FreqxMaxSlider.Value = 20000;
            app.SenxButton = uibutton(app.UIFigure, 'push');
            app.SenxButton.ButtonPushedFcn = createCallbackFcn(app, @SenxButtonPushed, true);
            app.SenxButton.Text = 'Sen(x)';
            app.CosxButton = uibutton(app.UIFigure, 'push');
            app.CosxButton.ButtonPushedFcn = createCallbackFcn(app, @CosxButtonPushed, true);
            app.CosxButton.Text = 'Cos(x)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ecualizador

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end